classdef PlantSeg2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Slider         matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        OpenDirButton  matlab.ui.control.Button
        UIAxesSeg      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputImage = 0; % Description
        mask = 0;
        maskAlpha = 0.5;
    end
    
    methods (Access = private)
        
        function results = UpdateSegmentation(app)
            imshow(app.inputImage, "Parent", app.UIAxesSeg);
            hold(app.UIAxesSeg, "on");
            h = imshow(app.mask, "Parent", app.UIAxesSeg);
            %h = imagesc(app.mask, "Parent", app.UIAxesSeg);
            %colormap(app.UIAxesInput, 'jet');
            hold(app.UIAxesSeg, "off")
            [M,N] = size(app.mask);
            %alphaImage = ones(M,N) * app.maskAlpha;
            alphaImage = (single(app.mask) / 255) * app.maskAlpha;
            set(h, 'AlphaData', alphaImage);
            set(h, 'Tag', 'allData')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenDirButton
        function OpenDirButtonPushed(app, event)
            app.inputImage = imread("00220110719002.tif");
            app.mask = rgb2gray(app.inputImage);
            app.mask = uint8(app.mask > 100) * 255;
            app.mask
            class(app.mask)
            app.Slider.Value = app.maskAlpha;
            UpdateSegmentation(app);
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.maskAlpha = value;
            UpdateSegmentation(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSeg = uiaxes(app.UIFigure);
            app.UIAxesSeg.XTick = [];
            app.UIAxesSeg.YTick = [];
            app.UIAxesSeg.ZTick = [];
            app.OpenDirButton = uibutton(app.UIFigure, 'push');
            app.OpenDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirButtonPushed, true);
            app.OpenDirButton.Text = 'Open Dir';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantSeg2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end